classdef muscleultrasound < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UnderstandingUltrasoundUIFigure  matlab.ui.Figure
        OpenButton                    matlab.ui.control.Button
        FrameSliderLabel              matlab.ui.control.Label
        FrameSlider                   matlab.ui.control.Slider
        FasciaandFibersPanel          matlab.ui.container.Panel
        SelectfasciaButton            matlab.ui.control.Button
        SelectfibersButton            matlab.ui.control.Button
        ResetButton                   matlab.ui.control.Button
        TrackingPanel                 matlab.ui.container.Panel
        SelectRegionofInterestButton  matlab.ui.control.Button
        TrackButton                   matlab.ui.control.Button
        NumberofframesDropDownLabel   matlab.ui.control.Label
        FrameDropDown                 matlab.ui.control.DropDown
        TabGroup                      matlab.ui.container.TabGroup
        UltrasoundFileTab             matlab.ui.container.Tab
        usfileAx                      matlab.ui.control.UIAxes
        RegionofInterestTab           matlab.ui.container.Tab
        roiAx                         matlab.ui.control.UIAxes
        ResultsTab                    matlab.ui.container.Tab
        PlayButton                    matlab.ui.control.Button
        OutputPanel                   matlab.ui.container.Panel
        DiameterCheckBox              matlab.ui.control.CheckBox
        LengthCheckBox                matlab.ui.control.CheckBox
        PennationangleCheckBox        matlab.ui.control.CheckBox
        MovementCheckBox              matlab.ui.control.CheckBox
        NotesLabel                    matlab.ui.control.Label
        Notes                         matlab.ui.control.TextArea
    end


    properties (Access = private)
        amp % matrix of amplitude
        len %number of frames
        col %number of columns in matrix
        usMat %matrix of raw rf data
        curFrame=1; %current frame
        usInitstate % frame window, defined by region of interest
        roi % Region of Interest data
    end
    
    properties (Access = public)
        fascia % stores fascia line in struct with fascia.x and fascia.y
        fiber %stores fiber data in struct with fiber.x {fiber number} and fiber.y {fiber number}
        fibNum=1;
        usTrackData;
    end
    
    methods (Access = private)
        %where all the functions live
        %state function
        function st = state(app,initstate)
            %Courtesty of Mingxin Zheng
            % Unfortunately, MATLAB use column-first method to arrange 2D Matrix, or
            % a 2D image. So we need to use a function to make things compatible.
            
            % check if numbers need to be integer
            if any(initstate - round(initstate))
                disp('warning: initstate is not an integer vector');
            end
            
            initstate = round(initstate);
            y = initstate(1);% x axis at the Top left corner
            x = initstate(2);
            st.w = initstate(3);% width of the rectangle
            st.h = initstate(4);% height of the rectangle
            
            st.x1 = x;
            st.x2 = x+st.h-1;
            st.y1 = y;
            st.y2 = y+st.w-1;
        end
        
        %kernel function
        function kn = kernel(app,set,stateVar)
            %Courtesy of Mingxin Zheng
            % search radius in X/Y direction
            sx = set(1);
            sy = set(2);
            rx = set(3);
            cy = set(4);
            
            kn.sx = sx;
            kn.sy = sy;
            % # locs (rows,cols) that need to be tracked
            kn.rx = rx;
            kn.cy = cy;
            
            % region of interest width and height
            kn.w = stateVar.w;
            kn.h = stateVar.h;
            
            % default minimum elements in the lateral/axial direction
            hx = 7;
            hy = 1;
            
            if kn.h/rx/2 > hx
                hx = floor(kn.h/rx/2);
            end
            
            if kn.w/cy/2 > hy
                hy = floor(kn.w/cy/2);
            end
            
            kn.hx = hx;
            kn.hy = hy;
            
        end
        
        %NCorrEst Function
        function data = NCorrEst(app,tpl,tar,st,kn)
            %Courtesy of Mingxin Zheng
            % tpl: template of 2D rf data, matrix, size: # of Time Samples by # RF
            % Lines (e.g. 1870 x 128)
            
            % tar: target of 2D rf data, matrix, same size as template.
            
            
            % note: add point grid, remove usage of xSkip and ySkip
            % st: upper left coodinates (x,y) of the object in the template
            
            % kn: kernel which have settings, such as the search range (sx,sy)-how far it looks, add extra in, the
            % width of templates (rx, cy) and the extra samples to correlate
            % (hx,hy)
            
            x1 = st.x1; y1=st.y1;
            
            % kn: kernel
            % kn.rx & kn.cy specifies the width and height of the object's image in
            % the template
            
            rx = kn.rx; cy = kn.cy;
            
            u = zeros(rx,cy);
            v = u;
            res = u;
            %update kn fields
            [locsx,locsy,kn] = locs(app,kn);
            
            
            hx = kn.hx; hy = kn.hy;
            
            % check if it is out of bound
            % kn.sx & kn.sy: specifies the search range of image between frames,
            % and the displacement of the object should not be larger than [sx,sy]
            sx1 = min([kn.sx, x1+locsx(1)-hx-1]);
            sx1 = max(sx1,0);
            
            sx2 = min([kn.sx, size(tpl,1)-(x1+locsx(end))-hx]);
            sx2 = max(sx2,0);
            
            sy1 = min([kn.sy, y1+locsy(1)-hy-1]);
            sy1 = max(sy1,0);
            
            sy2 = min([kn.sy, size(tpl,2)-(y1+locsy(end))-hy]);
            sy2 = max(sy2,0);
            
            
            mask = accelerate(app,tpl,kn);
            
            for k = 1:rx
                for p = 1:cy
                    ix = locsx(k);
                    iy = locsy(p);
                    val = (x1+ix)>0 & (x1+ix)<=size(mask,1) & (y1+iy)>0 & (y1+iy)<=size(mask,2);
                    if val&&(mask(x1+ix,y1+iy)|~any([rx-1,cy-1])) == 1
                        
                        ref    = tpl(x1+ix-hx   :x1+ix+hx   , ...
                            y1+iy-hy   :y1+iy+hy);
                        target = tar(x1+ix-hx-sx1:x1+ix+hx+sx2, ...
                            y1+iy-hy-sy1:y1+iy+hy+sy2);
                        c = normxcorr2(ref,target);
                        [res(k,p),ind] = max(c(:));
                        [mr,~] = size(c);
                        
                        % suppress warning
                        
                        v_subest = 0;
                        if (ind>mr) && (ind+mr)<numel(c)
                            y = [c(ind-mr) c(ind) c(ind+mr)];
                            v_subest = QuadFit(app,y);
                        end
                        u(k,p) = mod(ind,mr)-(2*hx+1)-sx1;
                        v(k,p) = ceil(ind/mr)-(2*hy+1)-sy1+v_subest;
                    end
                end
                data.u = u;
                data.v = v;
                data.res = res;
            end
            
        end
        
        %accelerate function
        function mask = accelerate(app,rf,kn)
            %Courtesy of Mingxin Zheng
            env = abs(hilbert(double(rf)));
            win= ones(kn.hx*2+1,kn.hy*2+1);
            env_fil = imfilter(env,win/sum(win(:)));
            mask = env_fil>150;
            mask(:,1:kn.hy) = 0;
            mask(:,end-kn.hy:end) = 0;
            mask(1:kn.hx,:) = 0;
            mask(end-kn.hx:end,:) = 0;
        end
        
        %locs function
        function [x,y,kn] = locs(app,kn)
            %Courtesy of Mingxin Zheng
            rx = kn.rx;
            cy = kn.cy;
            w = kn.w;
            h = kn.h;
            
            if rx>h || cy>w
                error('number of elements in rx/cy needs to be larger than h/w');
            end
            
            if rx ~= round(rx) || cy ~=round(cy)
                error('kn containts number that is not integer');
            end
            
            if rx == 1
                x = round(h/2);
                kn.hx = max(kn.hx,floor(kn.h/2));
            else
                x = round(linspace(0,h-1,rx));
                kn.hx = max(kn.hx,round(mean(diff(x))/2));
            end
            
            if cy == 1
                y = round(w/2);
                kn.hy = max(kn.hy,floor(kn.w/2));
            else
                y = round(linspace(0,w-1,cy));
                kn.hy = max(kn.hy,round(mean(diff(y))/2));
            end
            
        end
        
        function [output] = QuadFit(app,y)
            %Courtesy of Mingxin Zheng
            temp1 = y(1) - y(3);
           	temp2 = y(1) + y(3) - 2*y(2);
            if  abs(temp1)<1e-6
              		output = 0;
            else
              		answer = temp1/(temp2*2.0);
              		
                if answer<1
                    output = answer;
                else
                    output = 0.0;
                end
            end
            
        end
        
    end


    methods (Access = private)

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
                        %opens the desired file and loads data
            %select file
            fil = uigetfile;            % open dialog box to choose file
            usData = load(fil);           % load file into struct
            set(app.Notes,'Value','File loaded. Extracting RF data');
            
            %change from struct to 3D matrix
            names=fieldnames(usData); %extract names of frames
            app.len=length(names); %find number of frames
            set(app.FrameSlider,'Limits',[1, app.len]); %set the slider to the number of frames in file
            [r, app.col]=size(eval(['usData.' names{1}])); %find size of each matrix
            mat=zeros(r,app.col,app.len); %preallocate mat
            for i=1:app.len
                mat(:,:,i)=eval(['usData.' names{i}]); %enter frame information
            end
            app.usMat=mat;
            set(app.Notes,'Value','RF data loaded. Calculating Hilbert transform.');
            %convert from RF to amplitude for plotting
            htf=(zeros(r,app.col,app.len)); %preallocate htf
            for i = 1:app.len
                htf(:,:,i)=hilbert(mat(:,:,i));
            end
            hamp=abs(htf); %hamp is the analytic envelope
            ctr=5; %compression parameter
            app.amp=20*log10(hamp/ctr); % amplitude in dB
            
            %plot the image
            set(app.Notes,'Value','Plotting ultrasound image.');
            image(app.usfileAx,app.amp(:,:,1))
            colormap(app.usfileAx,gray)
            title(app.usfileAx,'Frame 1');
            axis(app.usfileAx,[0 app.col 0 r]);
        end

        % Value changed function: FrameSlider
        function FrameSliderValueChanged(app, event)
            %allows the user to scroll through the frames
            value = app.FrameSlider.Value;
            app.curFrame=round(value); % to ensure real numbers
            set(app.Notes,'Value',['Plotting frame ', num2str(app.curFrame)])
            image(app.usfileAx,app.amp(:,:,app.curFrame)); %plot the image of that frame
            title(app.usfileAx,['Frame ', num2str(app.curFrame)]); %change title to frame number
            
            %if the region of interest has been selected, make same changes for ROI
            if ~isempty(app.roi)
                image(app.roiAx,app.amp(app.roi.y1:app.roi.y2,app.roi.x1:app.roi.x2,app.curFrame))
                colormap(app.roiAx,gray)
                title(app.roiAx,['Frame ', num2str(app.curFrame)]);
            end
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            %runs through the frames to the end
            set(app.Notes,'Value','Playing ultrasound video');
            for i=app.curFrame:app.len
                image(app.usfileAx,app.amp(:,:,i));
                title(app.usfileAx,['Frame ', num2str(i)]);
                set(app.FrameSlider,'Value',i)
                pause(0.2)
            end
            
            %if region of interest has been select play through region of interest too
            %for some reason this part is not working...
            if ~isempty(app.roi)
                for j=app.curFrame:app.len
                    image(app.roiAx,app.amp(app.roi.y1:app.roi.y2,app.roi.x1:app.roi.x2,j))
                    title(app.roiAx,['Frame', num2str(j)]);
                end
            end
        end

        % Button pushed function: SelectfasciaButton
        function SelectfasciaButtonPushed(app, event)
            %selects the fascia line (must be in new figure because ginput not supported)
            set(app.Notes,'Value','Opening figure to select fascia')
            f=figure;
            image(app.amp(:,:,app.curFrame));
            colormap(gray)
            [x_getfascia, y_getfascia]=ginput(2);
            close(f)
            
            %extend graph of fascia
            set(app.Notes,'Value','Plotting fascia line');
            P=polyfit(x_getfascia,y_getfascia,1);
            x_fascia=1:app.col;
            y_fascia=P(1)*x_fascia+P(2);
            
            %plot the fascia line in red over the image
            image(app.usfileAx,app.amp(:,:,app.curFrame));
            hold(app.usfileAx,'on');
            plot(app.usfileAx,x_fascia,y_fascia,'r','LineWidth',2);
            hold(app.usfileAx,'off');
            
            %make the data about selected fascia line accessible
            app.fascia.x=x_fascia;
            app.fascia.y=y_fascia;
        end

        % Button pushed function: SelectfibersButton
        function SelectfibersButtonPushed(app, event)
            %select a fiber line
            set(app.Notes,'Value','Opening figure to select fiber')
            f=figure;
            image(app.amp(:,:,app.curFrame));
            colormap(gray)
            [x_getfib, y_getfib]=ginput(2); %save x and y values of fiber coordinates selected
            close(f);
            
            %extend line to graph fiber
            set(app.Notes,'Value',['Plotting fiber ', num2str(app.fibNum), ' line']);
            P2=polyfit(x_getfib,y_getfib,1);
            x_fiber=1:app.col;
            y_fiber=P2(1)*x_fiber+P2(2); 
            
            %plot of fiber line
            hold(app.usfileAx,'on');
            plot(app.usfileAx,x_fiber,y_fiber,'b','LineWidth',2);
            hold(app.usfileAx,'off');
            
            %save fiber data
            app.fiber.x{app.fibNum}=x_fiber;
            app.fiber.y{app.fibNum}=y_fiber;
            app.fibNum=app.fibNum+1;
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            %clears fiber and fascia selections
            set(app.Notes,'Value','Clearing fascia and fiber selections.');
            app.fascia=[];
            app.fiber=[];
            app.fibNum=1;
            image(app.usfileAx,app.amp(:,:,app.curFrame));
        end

        % Button pushed function: SelectRegionofInterestButton
        function SelectRegionofInterestButtonPushed(app, event)
            %selects a region of interest for tracking and display in the region of interest tab
            set(app.Notes,'Value','Opening figure to select ROI')
            f=figure;
            image(app.amp(:,:,app.curFrame));
            colormap(gray)
            initstate=getrect(f);
            close(f)
            initstate=round(initstate);
            app.usInitstate=initstate;
            %get corresponding x and y values
            x1=initstate(1);
            x2=x1+initstate(3);
            y1=initstate(2);
            y2=y1+initstate(4);
            %save region of interest
            app.roi.x1=x1;
            app.roi.x2=x2;
            app.roi.y1=y1;
            app.roi.y2=y2;
            app.roi.w=initstate(3);
            app.roi.h=initstate(4);
            %plots a box on the main axes
            set(app.Notes,'Value','Plotting ROI. Open ROI tab to view full size.');
            hold(app.usfileAx,'on');
            rectangle(app.usfileAx,'Position',initstate,'EdgeColor','g','LineWidth',2);
            hold(app.usfileAx,'off');
            %plots the zoomed in version in the ROI tab
            axis(app.roiAx,[0 initstate(3) 0 initstate(4)]);
            image(app.roiAx,app.amp(y1:y2,x1:x2,app.curFrame));
            colormap(app.roiAx,gray);
            title(app.roiAx,['Frame ', num2str(app.curFrame)]);
        end

        % Button pushed function: TrackButton
        function TrackButtonPushed(app, event)
            %tracks through set number of frames
            set(app.Notes,'Value','Setting up tracking.')
            
            %imports initstate and creates st
            initstate=app.usInitstate;
            x1=initstate(1);
            y1=initstate(2);
            w=initstate(3);
            h=initstate(4);
            st = state(app,initstate);
            
            %search range
            sX=10;
            sY=20;
            
            %how much of the ROI to search
            rX=round(h/2);
            cY=round(w/2);
            method = [sX, sY, rX, cY];
            kn = kernel(app,method,st);
            
            %frames to track
            initframe = app.curFrame;
            numframes = str2double(get(app.FrameDropDown,'Value'));
            endframe = initframe+numframes;
            if endframe > app.len
                endframe = app.len;
            end
            set(app.Notes,'Value',['Tracking from ', num2str(initframe) ' to ', num2str(endframe), '. View Region of Interest tab for quiver.']);
            
            %preallocate
            matrix_meanu=0;
            matrix_meanv=0;
            num=1;
            trackData=[];
            
            %load rf data
            mat=app.usMat;
            
            %%tracking cycles through frames
            for i= initframe:(endframe-1)
                disp(i)
                if i==initframe
                    %generates the Target and template
                    st = state(app,initstate);
                    templ= mat(:,:,i);
                    tar= mat(:,:,i+1);
                    data = NCorrEst(app,templ,tar,st,kn);
                    %initalizes the mean u and v vectors for later
                    %statistical use
                    Cart_meanu_vec=zeros(length(initframe:endframe)-1,1);
                    Cart_meanv_vec=zeros(length(initframe:endframe)-1,1);
                else
                    %alters the ROI depending on how the movement from the last frame
                    x1=round(x1 + Cart_meanu);
                    y1=round(y1 + Cart_meanv);
                    %size of region of interest remains the same
                    initstate = [x1, y1, w, h];
                    st = state(app,initstate);
                    %method stays same
                    kn = kernel(app,method,st);
                    %generates the Target and template
                    templ= mat(:,:,i);
                    tar= mat(:,:,i+1);
                    data = NCorrEst(app,templ,tar,st,kn);
                    %rectangle('Position',initstate,'EdgeColor','y')
                end
                
                % Plot new ROI
                x1=initstate(1);
                x2=x1+initstate(3);
                y1=initstate(2);
                y2=y1+initstate(4);
                image(app.roiAx,app.amp(y1:y2,x1:x2,i));
                
                hold(app.roiAx,'on')
                
                % Create quiver
                qdata=quiver(app.roiAx,data.v, data.u);
                title(app.roiAx,['quiver of Temp: ' num2str(i) ' to Tar: ' num2str(i+1)])
                %Generates the mean u and v vectors
                fakeu=zeros(size(data.v));
                fakev=fakeu;
                midx=round(rX/2);
                midy=round(cY/2);
                nonz_u= data.u((data.u ~= 0));
                matrix_meanu=mean(nonz_u);
                disp(["meanu:" matrix_meanu])
                
                nonz_v= data.v((data.v ~= 0));
                matrix_meanv=mean(nonz_v);
                disp(["meanv:" matrix_meanv])
                
                %Middle of figure is first mean vec and
                %decending from there is +1
                fakeu(midx+i,midy)=matrix_meanu;
                fakev(midx+i,midy)=matrix_meanv;
                Cart_meanu=matrix_meanv;
                Cart_meanv=matrix_meanu;
                
                Cart_meanu_vec(i)=Cart_meanu;
                Cart_meanv_vec(i)=Cart_meanv;
                %plotting the mean vector of the entire ROI
                qdata2=quiver(app.roiAx,fakev,fakeu);
                qdata2.LineWidth=3;
                qdata2.AutoScaleFactor=9;
                qdata2.MaxHeadSize=5;
                set(app.roiAx,'Ydir','reverse') %need to reverse the graph
                hold(app.roiAx,'off')
                trackData{num}=data;
                num=num+1;
                pause(1)
            end
            app.usTrackData=trackData;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UnderstandingUltrasoundUIFigure = uifigure;
            app.UnderstandingUltrasoundUIFigure.Name = 'Understanding Ultrasound';
            app.OpenButton = uibutton(app.UnderstandingUltrasoundUIFigure, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.FrameSliderLabel = uilabel(app.UnderstandingUltrasoundUIFigure);
            app.FrameSliderLabel.Text = 'Frame';
            app.FrameSlider = uislider(app.UnderstandingUltrasoundUIFigure);
            app.FrameSlider.Limits = [1 100];
            app.FrameSlider.ValueChangedFcn = createCallbackFcn(app, @FrameSliderValueChanged, true);
            app.FrameSlider.Value = 1;
            app.FasciaandFibersPanel = uipanel(app.UnderstandingUltrasoundUIFigure);
            app.FasciaandFibersPanel.Title = 'Fascia and Fibers';
            app.SelectfasciaButton = uibutton(app.FasciaandFibersPanel, 'push');
            app.SelectfasciaButton.ButtonPushedFcn = createCallbackFcn(app, @SelectfasciaButtonPushed, true);
            app.SelectfasciaButton.Text = 'Select fascia';
            app.SelectfibersButton = uibutton(app.FasciaandFibersPanel, 'push');
            app.SelectfibersButton.ButtonPushedFcn = createCallbackFcn(app, @SelectfibersButtonPushed, true);
            app.SelectfibersButton.Text = 'Select fibers';
            app.ResetButton = uibutton(app.FasciaandFibersPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.TrackingPanel = uipanel(app.UnderstandingUltrasoundUIFigure);
            app.TrackingPanel.Title = 'Tracking';
            app.SelectRegionofInterestButton = uibutton(app.TrackingPanel, 'push');
            app.SelectRegionofInterestButton.ButtonPushedFcn = createCallbackFcn(app, @SelectRegionofInterestButtonPushed, true);
            app.SelectRegionofInterestButton.Text = 'Select Region of Interest';
            app.TrackButton = uibutton(app.TrackingPanel, 'push');
            app.TrackButton.ButtonPushedFcn = createCallbackFcn(app, @TrackButtonPushed, true);
            app.TrackButton.Text = 'Track';
            app.NumberofframesDropDownLabel = uilabel(app.TrackingPanel);
            app.NumberofframesDropDownLabel.Text = 'Number of frames';
            app.FrameDropDown = uidropdown(app.TrackingPanel);
            app.FrameDropDown.Items = {'5', '10', '15', '20'};
            app.FrameDropDown.Value = '5';
            app.TabGroup = uitabgroup(app.UnderstandingUltrasoundUIFigure);
            app.UltrasoundFileTab = uitab(app.TabGroup);
            app.UltrasoundFileTab.Title = 'Ultrasound File';
            app.usfileAx = uiaxes(app.UltrasoundFileTab);
            xlabel(app.usfileAx, 'Line number')
            ylabel(app.usfileAx, 'Time Sample')
            app.RegionofInterestTab = uitab(app.TabGroup);
            app.RegionofInterestTab.Title = 'Region of Interest';
            app.roiAx = uiaxes(app.RegionofInterestTab);
            xlabel(app.roiAx, 'Line number')
            ylabel(app.roiAx, 'Time Sample')
            app.ResultsTab = uitab(app.TabGroup);
            app.ResultsTab.Title = 'Results';
            app.PlayButton = uibutton(app.UnderstandingUltrasoundUIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.OutputPanel = uipanel(app.UnderstandingUltrasoundUIFigure);
            app.OutputPanel.Title = 'Output';
            app.DiameterCheckBox = uicheckbox(app.OutputPanel);
            app.DiameterCheckBox.Text = 'Diameter';
            app.LengthCheckBox = uicheckbox(app.OutputPanel);
            app.LengthCheckBox.Text = 'Length';
            app.PennationangleCheckBox = uicheckbox(app.OutputPanel);
            app.PennationangleCheckBox.Text = 'Pennation angle';
            app.MovementCheckBox = uicheckbox(app.OutputPanel);
            app.MovementCheckBox.Text = 'Movement';
            app.NotesLabel = uilabel(app.UnderstandingUltrasoundUIFigure);
            app.NotesLabel.Text = 'Notes';
            app.Notes = uitextarea(app.UnderstandingUltrasoundUIFigure);
            app.Notes.Editable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = muscleultrasound

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UnderstandingUltrasoundUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UnderstandingUltrasoundUIFigure)
        end
    end
end